classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        rawheightplot          matlab.ui.control.UIAxes
        processeddataplot      matlab.ui.control.UIAxes
        SelectButton           matlab.ui.control.Button
        LoaddataButton         matlab.ui.control.Button
        ProcessdataButton      matlab.ui.control.Button
        LayeroutlinesCheckBox  matlab.ui.control.CheckBox
        LocationEditField      matlab.ui.control.EditField
        TimeSlider             matlab.ui.control.Slider
        DisplayLabel           matlab.ui.control.Label
        DisplayDropDown        matlab.ui.control.DropDown
        RawCbar                matlab.ui.control.UIAxes
        ProcCbar               matlab.ui.control.UIAxes
        Panel                  matlab.ui.container.Panel
        rawmin                 matlab.ui.control.EditField
        rawmax                 matlab.ui.control.EditField
        procmin                matlab.ui.control.EditField
        procmax                matlab.ui.control.EditField
        RawLabel               matlab.ui.control.Label
        ProcessedLabel         matlab.ui.control.Label
        MinLabel               matlab.ui.control.Label
        MaxLabel               matlab.ui.control.Label
        HeightlimitsmLabel     matlab.ui.control.Label
        autoraw                matlab.ui.control.Button
        autoproc               matlab.ui.control.Button
        HeightmapsurfacecorrectionLabel  matlab.ui.control.Label
        Panel_2                matlab.ui.container.Panel
        ExportdataLabel        matlab.ui.control.Label
        NameEditFieldLabel     matlab.ui.control.Label
        NameEditField          matlab.ui.control.EditField
        DataDropDownLabel      matlab.ui.control.Label
        DataDropDown           matlab.ui.control.DropDown
        ExportButton           matlab.ui.control.Button
        PlayButton             matlab.ui.control.StateButton
        Panel_3                matlab.ui.container.Panel
        h_adjust_edit          matlab.ui.control.EditField
        HeightadjustmentLabel  matlab.ui.control.Label
        mLabel                 matlab.ui.control.Label
        TimePanel              matlab.ui.container.Panel
        time_edit              matlab.ui.control.EditField
    end

    
    properties (Access = private)
        hdataraw % Raw loaded height data.
        hdataproc % Processed height data.
        labels % Labeled points.
        baseline % Interpolated zero height plane.
        layers % Layer map.
        threshs % Threholds for each time point.
        pts % Scatter plot of labeled points.
        rawhim % Image of raw height being displayed.
        prochim % Image of processed data being displayed.
        processed % Keeps track of whether processing has happened or not.
        t % Current time.
        h_adjust % Global djustment for raw height data for each frame.
    end
    
    methods (Access = private)
        function plotraw(app)
            
            app.h_adjust_edit.Value = sprintf('%1.3f',app.h_adjust{app.t});
            app.rawhim.CData = app.hdataraw{app.t}-app.h_adjust{app.t};
            
            if numel(app.labels)>0
                updatepts(app)
            end
            
        end
        
        function plotproc(app)
            if app.processed
                if numel(app.labels)>0
                    if app.DisplayDropDown.Value == "Zero height"
                        app.prochim.CData = app.baseline{app.t};
                    elseif app.DisplayDropDown.Value == "Processed height"
                        app.prochim.CData = app.hdataproc{app.t};
                    elseif app.DisplayDropDown.Value == "Layers"
                        app.prochim.CData = app.layers{app.t};
                    end
                    if app.LayeroutlinesCheckBox.Value
                        layeds = imdilate(edge(app.layers{app.t}),strel('disk',3));
                        app.prochim.AlphaData = ~layeds;
                    else
                        app.prochim.AlphaData = 1;
                    end
                end
                app.ProcCbar.XTickLabel = [0; ...
                    str2double(sprintf('%1.2f',0.25*(app.processeddataplot.CLim(2)-app.processeddataplot.CLim(1)))); ...
                    str2double(sprintf('%1.2f',0.5*(app.processeddataplot.CLim(2)-app.processeddataplot.CLim(1)))); ...
                    str2double(sprintf('%1.2f',0.75*(app.processeddataplot.CLim(2)-app.processeddataplot.CLim(1)))); ...
                    str2double(sprintf('%1.2f',(app.processeddataplot.CLim(2)-app.processeddataplot.CLim(1))))];
            end
        end
        
        function updatepts(app)
            
            xs = [app.labels([app.labels.t]==app.t).x];
            ys = [app.labels([app.labels.t]==app.t).y];
            cs = [app.labels([app.labels.t]==app.t).l];
            cmap = myxocmap;
            cmapsz = size(cmap);
            maxc = max(cs);
            minc = min(cs);
            if maxc~=minc
                cs = round((cs-minc)*(cmapsz(1)-1)/(maxc-minc)+1);
            else
                cs = ones(size(cs));
            end
            
            cs = cmap(cs,:);
            app.pts.XData = xs;
            app.pts.YData = ys;
            app.pts.CData = cs;
        end
        
        function new_t(app, tt)
            if tt<app.TimeSlider.Limits(1)
                tt = app.TimeSlider.Limits(1);
            elseif tt>app.TimeSlider.Limits(2)
                tt = app.TimeSlider.Limits(2);
            end
            app.t = tt;
            app.TimeSlider.Value = tt;
            app.time_edit.Value = num2str(tt);
            plotraw(app)
            plotproc(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.t = 1;
            app.rawheightplot.Colormap = myxocmap;
            app.processeddataplot.Colormap = myxocmap;
            app.processed = false;
            app.rawhim = imagesc(app.rawheightplot,zeros(768,1024));
            hold(app.rawheightplot,'on');
            app.pts = scatter(app.rawheightplot,[],[],20,[],'filled','MarkerEdgeColor','k');
            app.prochim = imagesc(app.processeddataplot,zeros(768,1024));
            hold(app.processeddataplot,'on');
            %Make colorbars
            x = 1:512;
            y = 1:20;
            [xx,~] = meshgrid(x,y);
            cbar = real2rgb(xx,myxocmap,[1 512]);

            image(app.RawCbar,cbar);
            image(app.ProcCbar,cbar);
            
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            fold = uigetdir;
            app.LocationEditField.Value = fold;
        end

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)
            fpath = [app.LocationEditField.Value '/'];
            ts = getts(fpath);
            for tt = 1:numel(ts)
                h = heightdata(fpath,tt);
                app.hdataraw{tt} = h;
                if tt>1 
                    app.h_adjust{tt} = mean(app.hdataraw{tt}-app.hdataraw{1},'all');
                else
                    app.h_adjust{tt} = 0;
                end
            end
            app.TimeSlider.Limits = [1 numel(ts)];
            app.autorawButtonPushed(app);
            plotraw(app)

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
            l = str2double(key);
            C = get(app.rawheightplot,'CurrentPoint');
            drs = [];
            if ~isnan(l)
                if numel(app.labels)>0
                    clabels = app.labels([app.labels.t]==app.t);
                    if numel(clabels)>0
                        dxs = [clabels.x]-C(1,1);
                        dys = [clabels.y]-C(1,2);
                        drs = sqrt(dxs.^2+dys.^2);
                    end
                else
                    drs = 50;
                end
                if isempty(drs)
                    drs = 100;
                end
                if min(drs)>20
                    app.labels(end+1).x = C(1,1);
                    app.labels(end).y = C(1,2);
                    app.labels(end).l = round(l);
                    app.labels(end).t = app.t;
                    updatepts(app)
                end
            elseif key == 'd'
                ctime = [app.labels.t] == app.t;
                dxs = [app.labels.x] - C(1,1);
                dys = [app.labels.y] - C(1,2);
                dists = sqrt(dxs.^2+dys.^2);
                
                closest = dists == min(dists(ctime));
                app.labels(closest) = [];
                updatepts(app)
            elseif key == "leftarrow"
                app.new_t(app.t-1);
            elseif key == "rightarrow"
                app.new_t(app.t+1);
            end

        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            
        end

        % Button pushed function: ProcessdataButton
        function ProcessdataButtonPushed(app, event)
            x = 1:1024;
            y = 1:768;
            [xq,yq] = meshgrid(x,y);
            ts = unique([app.labels.t]);
            wb = waitbar(0,"Processing please wait...");
            
            for tt = ts
                
                clabels = app.labels([app.labels.t]==tt);
                ls = unique([clabels.l]);
                llays = [];
                
                for l = ls
                    cllabels = clabels([clabels.l]==l);
                    inds = sub2ind(size(xq),round([cllabels.y]),round([cllabels.x]));
                    
                    hs = app.hdataraw{tt}-app.h_adjust{tt};
                    hs = hs(inds);
                    
                    llays{end+1} = griddata([cllabels.x],[cllabels.y],hs,xq,yq,'v4');
                    rel = zeros(size(xq));
                    rel(inds) = 1;
                    llays{end}(~imdilate(rel,strel('disk',100))) = NaN;
                end
                basel = min(ls);
                clays = 0;
                for l = ls(ls>basel)
                    dlays = llays{ls==l} - llays{ls==l-1};
                    clays(end+1) = sum(clays) + mean(dlays,'all','omitnan');
                end
                
                axs = [clabels.x];
                ays = [clabels.y];
                ls = [clabels.l];
                inds = sub2ind(size(xq),round([clabels.y]),round([clabels.x]));
                hs = app.hdataraw{tt}-app.h_adjust{tt};
                
                ahs = hs(inds)-clays(ls-basel+1);
                app.threshs{tt} = conv(clays,[0.5 0.5],'valid');
                app.baseline{tt} = griddata(axs,ays,ahs,xq,yq,'v4');
            end
            for tt = 1:numel(app.hdataraw)
                t1 = max(ts(ts<=tt));
                t2 = min(ts(ts>tt));
                
                if isempty(t1)
                    app.baseline{tt} = app.baseline{t2};
                    app.threshs{tt} = app.threshs{t2};
                elseif isempty(t2)
                    app.baseline{tt} = app.baseline{t1};
                    app.threshs{tt} = app.threshs{t1};
                else
                    p2 = (tt-t1)/(t2-t1);
                    p1 = 1-p2;

                    thresh1 = app.threshs{t1};
                    thresh2 = app.threshs{t2};
                    
                    An = numel(thresh1);
                    Bn = numel(thresh2);
                    dn = Bn - An;
                    if dn~=0
                        if dn<0
                            B1 = thresh2;
                            A1 = thresh1;
                        elseif dn>0
                            B1 = thresh1;
                            A1 = thresh2;
                        end
                        
                        minb = 100;
                        minbd = 1000;
                        for b = 1:abs(dn)+1
                            Bt = NaN(1,max(An,Bn));
                            Bt(b:b+min(An,Bn)-1) = B1;
                            diff = Bt-A1;
                            if sum(diff.^2,'omitnan')<minbd
                                minb = b;
                                minbd = sum(diff.^2,'omitnan');
                            end
                        end
                        
                        Bt = NaN(1,numel(A1));
                        Bt(minb:minb+numel(B1)-1) = B1;
                        Bt(isnan(Bt)) = A1(isnan(Bt));
                        if dn<0
                            thresh2 = Bt;
                        else
                            thresh1 = Bt;
                        end
                    end

                    app.baseline{tt} = p1*app.baseline{t1}+p2*app.baseline{t2};
                    app.threshs{tt} = p1*thresh1+p2*thresh2;
                end
                app.hdataproc{tt} = (app.hdataraw{tt}-app.h_adjust{tt})-app.baseline{tt};
                clayer = NaN(size(app.hdataproc{tt}));
                for ll = 0:numel(app.threshs{tt})-1
                    clayer(isnan(clayer).*(app.hdataproc{tt}<app.threshs{tt}(ll+1))==1)=ll;
                end
                clayer(app.hdataproc{tt}>app.threshs{tt}(end))=numel(app.threshs{tt});
                app.layers{tt} = round(imgaussfilt(clayer,3));
                waitbar(tt/numel(app.hdataraw),wb)
            end
            
            close(wb);
            
            app.processed = true;
            app.LayeroutlinesCheckBox.Value = false;
            plotproc(app);
            plotraw(app);
            autorawButtonPushed(app);
            autoprocButtonPushed(app);
        end

        % Value changed function: DisplayDropDown
        function DisplayDropDownValueChanged(app, event)
            value = app.DisplayDropDown.Value;
            if app.processed
                if app.DisplayDropDown.Value == "Zero height"
                    app.processeddataplot.CLim = [0.1*min(min([app.baseline{:}])) 0.9*max(max([app.baseline{:}]))];
                elseif app.DisplayDropDown.Value == "Processed height"
                    app.processeddataplot.CLim = [0.1*min(min([app.hdataproc{:}])) 0.9*max(max([app.hdataproc{:}]))];
                elseif app.DisplayDropDown.Value == "Layers"
                    app.processeddataplot.CLim = [0.1*min(min([app.layers{:}])) 0.9*max(max([app.layers{:}]))];
                end
                
                app.autoprocButtonPushed(app)
                plotproc(app)
            end
        end

        % Value changed function: LayeroutlinesCheckBox
        function LayeroutlinesCheckBoxValueChanged(app, event)
            value = app.LayeroutlinesCheckBox.Value;
            if value
                layeredges = imdilate(edge(app.layers{app.t}),strel('disk',3));
                app.prochim.AlphaData = ~layeredges;
            else
                app.prochim.AlphaData = ones(768,1024);
            end
        end

        % Value changed function: rawmin
        function rawminValueChanged(app, event)
            value = app.rawmin.Value;
            app.rawheightplot.CLim(1) = str2double(value);
            app.RawCbar.XTickLabel = [0; ...
                str2double(sprintf('%1.2f',0.25*(app.rawheightplot.CLim(2)-app.rawheightplot.CLim(1)))); ...
                str2double(sprintf('%1.2f',0.5*(app.rawheightplot.CLim(2)-app.rawheightplot.CLim(1)))); ...
                str2double(sprintf('%1.2f',0.75*(app.rawheightplot.CLim(2)-app.rawheightplot.CLim(1)))); ...
                str2double(sprintf('%1.2f',(app.rawheightplot.CLim(2)-app.rawheightplot.CLim(1))))];
            
        end

        % Value changed function: rawmax
        function rawmaxValueChanged(app, event)
            value = app.rawmax.Value;
            app.rawheightplot.CLim(2) = str2double(value);
            app.RawCbar.XTickLabel = [0; ...
                str2double(sprintf('%1.2f',0.25*(app.rawheightplot.CLim(2)-app.rawheightplot.CLim(1)))); ...
                str2double(sprintf('%1.2f',0.5*(app.rawheightplot.CLim(2)-app.rawheightplot.CLim(1)))); ...
                str2double(sprintf('%1.2f',0.75*(app.rawheightplot.CLim(2)-app.rawheightplot.CLim(1)))); ...
                str2double(sprintf('%1.2f',(app.rawheightplot.CLim(2)-app.rawheightplot.CLim(1))))];
            
        end

        % Value changed function: procmin
        function procminValueChanged(app, event)
            value = app.procmin.Value;
            app.processeddataplot.CLim(1) = str2double(value);
            app.ProcCbar.XTickLabel = [0; ...
                str2double(sprintf('%1.2f',0.25*(app.processeddataplot.CLim(2)-app.processeddataplot.CLim(1)))); ...
                str2double(sprintf('%1.2f',0.5*(app.processeddataplot.CLim(2)-app.processeddataplot.CLim(1)))); ...
                str2double(sprintf('%1.2f',0.75*(app.processeddataplot.CLim(2)-app.processeddataplot.CLim(1)))); ...
                str2double(sprintf('%1.2f',(app.processeddataplot.CLim(2)-app.processeddataplot.CLim(1))))];
        end

        % Value changed function: procmax
        function procmaxValueChanged(app, event)
            value = app.procmax.Value;
            
            app.processeddataplot.CLim(2) = str2double(value);
            app.ProcCbar.XTickLabel = [0; ...
                str2double(sprintf('%1.2f',0.25*(app.processeddataplot.CLim(2)-app.processeddataplot.CLim(1)))); ...
                str2double(sprintf('%1.2f',0.5*(app.processeddataplot.CLim(2)-app.processeddataplot.CLim(1)))); ...
                str2double(sprintf('%1.2f',0.75*(app.processeddataplot.CLim(2)-app.processeddataplot.CLim(1)))); ...
                str2double(sprintf('%1.2f',(app.processeddataplot.CLim(2)-app.processeddataplot.CLim(1))))];
        end

        % Button pushed function: autoraw
        function autorawButtonPushed(app, event)
            app.rawmin.Value = sprintf('%1.2f',0.1*min(min([app.hdataraw{:}])));
            app.rawmax.Value = sprintf('%1.2f',0.9*max(max([app.hdataraw{:}])));
            app.rawmaxValueChanged(app);
            app.rawminValueChanged(app);
            
        end

        % Button pushed function: autoproc
        function autoprocButtonPushed(app, event)
            if app.DisplayDropDown.Value == "Zero height"
                app.procmin.Value = sprintf('%1.2f',0.1*min(min([app.baseline{:}])));
                app.procmax.Value = sprintf('%1.2f',0.9*max(max([app.baseline{:}])));
%                 app.processeddataplot.CLim = [0.1*min(min([app.baseline{:}])) 0.9*max(max([app.baseline{:}]))];
            elseif app.DisplayDropDown.Value == "Processed height"
                app.procmin.Value = sprintf('%1.2f',0.1*min(min([app.hdataproc{:}])));
                app.procmax.Value = sprintf('%1.2f',0.9*max(max([app.hdataproc{:}])));
%                 app.processeddataplot.CLim = [0.1*min(min([app.hdataproc{:}])) 0.9*max(max([app.hdataproc{:}]))];
            elseif app.DisplayDropDown.Value == "Layers"
                app.procmin.Value = sprintf('%1.2f',0.1*min(min([app.layers{:}])));
                app.procmax.Value = sprintf('%1.2f',0.9*max(max([app.layers{:}])));
%                 app.processeddataplot.CLim = [0.1*min(min([app.layers{:}])) 0.9*max(max([app.layers{:}]))];
            end
            app.procmaxValueChanged(app);
            app.procminValueChanged(app);

        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            folder = app.NameEditField.Value;
            fpath = [app.LocationEditField.Value '/'];
            [~,~,~] = mkdir(fpath,[folder '/']);
            
            
            for tt = app.TimeSlider.Limits(1):app.TimeSlider.Limits(2)
                name = sprintf('%06d.bin',tt-1);
                
                fID = fopen([fpath folder '/' name],'w');
                if app.DataDropDown.Value == "Zero height"
                    fwrite(fID,app.baseline{tt},'float');
                elseif app.DataDropDown.Value == "Processed height"
                    fwrite(fID,app.hdataproc{tt},'float');
                elseif app.DataDropDown.Value == "Layers"
                    fwrite(fID,app.layers{tt},'int8');
                end
                
                fclose(fID);
            end
            
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            value = app.PlayButton.Value;
            if value
                tt = app.t;
                while app.PlayButton.Value && tt<=app.TimeSlider.Limits(2)
                    app.PlayButton.Text = ' ▌▌';
                    app.PlayButton.FontSize = 16;

                    pause(0.1)
                    new_t(app,tt);
                    tt = tt+1;
                end
                app.PlayButton.Value = 0;
                app.PlayButton.Text = '►';
                app.PlayButton.FontSize = 24;
            else
                app.PlayButton.Text = '►';
                app.PlayButton.FontSize = 24;
            end
        end

        % Value changed function: time_edit
        function time_editValueChanged(app, event)
            value = str2double(app.time_edit.Value);
            app.new_t(value);
        end

        % Value changing function: TimeSlider
        function TimeSliderValueChanging(app, event)
            changingValue = round(event.Value);
            app.new_t(changingValue)
        end

        % Value changed function: h_adjust_edit
        function h_adjust_editValueChanged(app, event)
            value = app.h_adjust_edit.Value;
            app.h_adjust{app.t} = value;
            plotraw(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.rawheightplot = uiaxes(app.GridLayout);
            title(app.rawheightplot, '')
            xlabel(app.rawheightplot, '')
            ylabel(app.rawheightplot, '')
            app.rawheightplot.XTick = [];
            app.rawheightplot.YTick = [];
            app.rawheightplot.Visible = 'off';
            app.processeddataplot = uiaxes(app.GridLayout);
            title(app.processeddataplot, '')
            xlabel(app.processeddataplot, '')
            ylabel(app.processeddataplot, '')
            app.processeddataplot.XTick = [];
            app.processeddataplot.YTick = [];
            app.processeddataplot.Visible = 'off';
            app.SelectButton = uibutton(app.GridLayout, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.LoaddataButton = uibutton(app.GridLayout, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Text = 'Load data';
            app.ProcessdataButton = uibutton(app.GridLayout, 'push');
            app.ProcessdataButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessdataButtonPushed, true);
            app.ProcessdataButton.Text = 'Process data';
            app.LayeroutlinesCheckBox = uicheckbox(app.GridLayout);
            app.LayeroutlinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @LayeroutlinesCheckBoxValueChanged, true);
            app.LayeroutlinesCheckBox.Text = 'Layer outlines';
            app.LocationEditField = uieditfield(app.GridLayout, 'text');
            app.LocationEditField.Value = '/Users/kcopenhagen/Documents/Data/Monolayer/High frame rate/Data/190111KC2';
            app.TimeSlider = uislider(app.GridLayout);
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderValueChanging, true);
            app.DisplayLabel = uilabel(app.GridLayout);
            app.DisplayLabel.Text = 'Display:';
            app.DisplayDropDown = uidropdown(app.GridLayout);
            app.DisplayDropDown.Items = {'Zero height', 'Processed height', 'Layers'};
            app.DisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayDropDownValueChanged, true);
            app.DisplayDropDown.Value = 'Zero height';
            app.RawCbar = uiaxes(app.GridLayout);
            title(app.RawCbar, '')
            xlabel(app.RawCbar, 'Height ({\mu}m)')
            ylabel(app.RawCbar, '')
            app.RawCbar.XLim = [0 512];
            app.RawCbar.XAxisLocation = 'top';
            app.RawCbar.XTick = [0 128 256 384 512];
            app.RawCbar.XTickLabel = {'0'; '0.25'; '0.5'; '0.75'; '1'};
            app.RawCbar.YTick = [];
            app.ProcCbar = uiaxes(app.GridLayout);
            title(app.ProcCbar, '')
            xlabel(app.ProcCbar, 'Height ({\mu}m)')
            ylabel(app.ProcCbar, '')
            app.ProcCbar.XLim = [0 512];
            app.ProcCbar.XAxisLocation = 'top';
            app.ProcCbar.XTick = [0 128 256 384 512];
            app.ProcCbar.XTickLabel = {'0'; '0.25'; '0.5'; '0.75'; '1'};
            app.ProcCbar.YTick = [];
            app.Panel = uipanel(app.GridLayout);
            app.rawmin = uieditfield(app.Panel, 'text');
            app.rawmin.ValueChangedFcn = createCallbackFcn(app, @rawminValueChanged, true);
            app.rawmin.Value = '0';
            app.rawmax = uieditfield(app.Panel, 'text');
            app.rawmax.ValueChangedFcn = createCallbackFcn(app, @rawmaxValueChanged, true);
            app.rawmax.Value = '1';
            app.procmin = uieditfield(app.Panel, 'text');
            app.procmin.ValueChangedFcn = createCallbackFcn(app, @procminValueChanged, true);
            app.procmin.Value = '0';
            app.procmax = uieditfield(app.Panel, 'text');
            app.procmax.ValueChangedFcn = createCallbackFcn(app, @procmaxValueChanged, true);
            app.procmax.Value = '1';
            app.RawLabel = uilabel(app.Panel);
            app.RawLabel.Text = 'Raw:';
            app.ProcessedLabel = uilabel(app.Panel);
            app.ProcessedLabel.Text = 'Processed:';
            app.MinLabel = uilabel(app.Panel);
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.Panel);
            app.MaxLabel.Text = 'Max';
            app.HeightlimitsmLabel = uilabel(app.Panel);
            app.HeightlimitsmLabel.Text = 'Height limits (µm)';
            app.autoraw = uibutton(app.Panel, 'push');
            app.autoraw.ButtonPushedFcn = createCallbackFcn(app, @autorawButtonPushed, true);
            app.autoraw.Text = 'Auto';
            app.autoproc = uibutton(app.Panel, 'push');
            app.autoproc.ButtonPushedFcn = createCallbackFcn(app, @autoprocButtonPushed, true);
            app.autoproc.Text = 'Auto';
            app.HeightmapsurfacecorrectionLabel = uilabel(app.GridLayout);
            app.HeightmapsurfacecorrectionLabel.Text = 'Height map surface correction.';
            app.Panel_2 = uipanel(app.GridLayout);
            app.ExportdataLabel = uilabel(app.Panel_2);
            app.ExportdataLabel.Text = 'Export data';
            app.NameEditFieldLabel = uilabel(app.Panel_2);
            app.NameEditFieldLabel.Text = 'Name:';
            app.NameEditField = uieditfield(app.Panel_2, 'text');
            app.DataDropDownLabel = uilabel(app.Panel_2);
            app.DataDropDownLabel.Text = 'Data';
            app.DataDropDown = uidropdown(app.Panel_2);
            app.DataDropDown.Items = {'Zero height', 'Processed height', 'Layers'};
            app.DataDropDown.Value = 'Zero height';
            app.ExportButton = uibutton(app.Panel_2, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.PlayButton = uibutton(app.GridLayout, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = '►';
            app.Panel_3 = uipanel(app.GridLayout);
            app.h_adjust_edit = uieditfield(app.Panel_3, 'text');
            app.h_adjust_edit.ValueChangedFcn = createCallbackFcn(app, @h_adjust_editValueChanged, true);
            app.HeightadjustmentLabel = uilabel(app.Panel_3);
            app.HeightadjustmentLabel.Text = {'Height '; 'adjustment'};
            app.mLabel = uilabel(app.Panel_3);
            app.mLabel.Text = 'µm';
            app.TimePanel = uipanel(app.GridLayout);
            app.TimePanel.Title = 'Time:';
            app.time_edit = uieditfield(app.TimePanel, 'text');
            app.time_edit.ValueChangedFcn = createCallbackFcn(app, @time_editValueChanged, true);
            app.time_edit.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end